classdef clustering_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        NumberofdatapointsEditFieldLabel  matlab.ui.control.Label
        pointsEdit                      matlab.ui.control.NumericEditField
        pointsButton                    matlab.ui.control.Button
        NumberofclustersEditFieldLabel  matlab.ui.control.Label
        clustersEdit                    matlab.ui.control.NumericEditField
        clustersButton                  matlab.ui.control.Button
        centroidsCheck                  matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
    end


    properties (Access = public)
        xMax = 100; % x between 0 and xMax
        yMax = 100; % y between 0 and yMax    
    end

    properties (Access = private)
        xP = 0;
        yP = 0;
    end


    methods (Access = private)

        % Button pushed function: clustersButton
        function clustersButtonPushed(app, event)
            k = app.clustersEdit.Value;
            centr_show = app.centroidsCheck.Value;
            points = [app.xP;app.yP];
            [cluster, centr] = kMeans(k, points);
            if centr_show == true
                scatter(app.UIAxes,app.xP,app.yP,200,cluster,'.');
                app.UIAxes.NextPlot = 'add';
                scatter(app.UIAxes,centr(1,:),centr(2,:),...
                    'xk','LineWidth',1.5);
                app.UIAxes.NextPlot = 'replacechildren';
            else
                scatter(app.UIAxes,app.xP,app.yP,200,cluster,'.');
            end
        end

        % Button pushed function: pointsButton
        function pointsButtonPushed(app, event)
            numP = app.pointsEdit.Value;
            app.xP = app.xMax * rand(1,numP);
            app.yP = app.yMax * rand(1,numP);
            scatter(app.UIAxes,app.xP,app.yP,200,'.k');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot window')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 100];
            app.UIAxes.YLimMode = 'manual';
            app.NumberofdatapointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofdatapointsEditFieldLabel.Text = 'Number of datapoints';
            app.pointsEdit = uieditfield(app.UIFigure, 'numeric');
            app.pointsEdit.Limits = [0 10000];
            app.pointsEdit.RoundFractionalValues = 'on';
            app.pointsEdit.ValueDisplayFormat = '%.0f';
            app.pointsButton = uibutton(app.UIFigure, 'push');
            app.pointsButton.ButtonPushedFcn = createCallbackFcn(app, @pointsButtonPushed, true);
            app.pointsButton.Text = 'Generate random data points';
            app.NumberofclustersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofclustersEditFieldLabel.Text = 'Number of clusters';
            app.clustersEdit = uieditfield(app.UIFigure, 'numeric');
            app.clustersEdit.Limits = [1 10];
            app.clustersEdit.RoundFractionalValues = 'on';
            app.clustersEdit.ValueDisplayFormat = '%.0f';
            app.clustersEdit.Value = 1;
            app.clustersButton = uibutton(app.UIFigure, 'push');
            app.clustersButton.ButtonPushedFcn = createCallbackFcn(app, @clustersButtonPushed, true);
            app.clustersButton.Text = 'Cluster data points';
            app.centroidsCheck = uicheckbox(app.UIFigure);
            app.centroidsCheck.Text = 'Show cluster centroids';
            app.centroidsCheck.Value = true;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = clustering_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end